classdef MemoryMeds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PrescriptionsTab             matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        AddTab                       matlab.ui.container.Tab
        NotesTextArea                matlab.ui.control.TextArea
        NotesTextAreaLabel           matlab.ui.control.Label
        ExpirationEditField          matlab.ui.control.EditField
        ExpirationEditFieldLabel     matlab.ui.control.Label
        DosageEditField              matlab.ui.control.EditField
        DosageEditFieldLabel         matlab.ui.control.Label
        DatePrescrbedEditField       matlab.ui.control.EditField
        DatePrescrbedEditFieldLabel  matlab.ui.control.Label
        DrugNameEditField            matlab.ui.control.EditField
        DrugNameEditFieldLabel       matlab.ui.control.Label
        uploadButton                 matlab.ui.control.Button
        EditButton                   matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        HistoryTab                   matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        Image_2                      matlab.ui.control.Image
        VaccinationsTab              matlab.ui.container.Tab
        SettingsTab                  matlab.ui.container.Tab
        SettingsLabel                matlab.ui.control.Label
        PrescriptionsLabel           matlab.ui.control.Label
        VaccinationsLabel            matlab.ui.control.Label
        AccountButton                matlab.ui.control.Button
        PrescriptionsButton          matlab.ui.control.Button
        VaccinationsButton           matlab.ui.control.Button
        SettingsButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        RowCount = 1;
        ImagePath = "C:\Users\kevinh\OneDrive - MathWorks\Desktop\Demo Trash - Delete for space\MedHack_Yayz!\RX.png";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UIFigure.Units = "normalized";
%             app.UIFigure.Position = [0.3, 0.2, 0.4, 0.5];
%             app.UIFigure.Units = "pixels";
% 
%             app.TabGroup.Units= "normalized";
%             app.TabGroup.Position = [0, 0, 1, 1];
%             app.TabGroup.Units = "pixels";
        end

        % Button pushed function: PrescriptionsButton
        function PrescriptionsButtonPushed(app, event)
            app.TabGroup.Visible = "on";
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.NotesTextArea.Editable = "off";
            app.DrugNameEditField.Editable = "off";
            app.DatePrescrbedEditField.Editable = "off";
            app.DosageEditField.Editable = "off";
            app.ExpirationEditField.Editable = "off";

            app.EditButton.Enable = "on";
            app.uploadButton.Enable = "off";
            app.ApplyButton.Enable = "off";

            if isempty(app.UITable.Data)
                app.UITable.Data = "";
                app.UITable.Data(app.RowCount,1) = app.ImagePath;
                app.UITable.Data(app.RowCount,2) = app.DrugNameEditField.Value;
                app.UITable.Data(app.RowCount,3) = app.DatePrescrbedEditField.Value;
                app.UITable.Data(app.RowCount,4) = app.DosageEditField.Value;
                app.UITable.Data(app.RowCount,5) = app.ExpirationEditField.Value;
                app.UITable.Data(app.RowCount,6) = app.NotesTextArea.Value;
            else
                app.RowCount = size(app.UITable.Data,1)+1;
                app.UITable.Data(app.RowCount,1) = app.ImagePath;
                app.UITable.Data(app.RowCount,2) = app.DrugNameEditField.Value;
                app.UITable.Data(app.RowCount,3) = app.DatePrescrbedEditField.Value;
                app.UITable.Data(app.RowCount,4) = app.DosageEditField.Value;
                app.UITable.Data(app.RowCount,5) = app.ExpirationEditField.Value;
                app.UITable.Data(app.RowCount,6) = app.NotesTextArea.Value;
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.NotesTextArea.Editable = "on";
            app.DrugNameEditField.Editable = "on";
            app.DatePrescrbedEditField.Editable = "on";
            app.DosageEditField.Editable = "on";
            app.ExpirationEditField.Editable = "on";

            app.EditButton.Enable = "off";
            app.uploadButton.Enable = "on";
            app.ApplyButton.Enable = "on";
        end

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)
            filter = {'*.png','*.jpeg','*.jpg','*.tif','*.svg'}';
            [filename,folder] = uigetfile(filter);
            app.ImagePath = string(fullfile(folder,filename));
            Img = imread(app.ImagePath);
            app.Image.ImageSource =  Img;         
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            indices = app.UITable.Selection;
            
            app.Image_2.ImageSource = imread(app.UITable.Data(indices(1,1),1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsButton = uibutton(app.UIFigure, 'push');
            app.SettingsButton.Icon = fullfile(pathToMLAPP, 'Settings.PNG');
            app.SettingsButton.Text = '';
            app.VaccinationsButton = uibutton(app.UIFigure, 'push');
            app.VaccinationsButton.Icon = fullfile(pathToMLAPP, 'MaskIcon.png');
            app.VaccinationsButton.Text = '';
            app.PrescriptionsButton = uibutton(app.UIFigure, 'push');
            app.PrescriptionsButton.ButtonPushedFcn = createCallbackFcn(app, @PrescriptionsButtonPushed, true);
            app.PrescriptionsButton.Icon = fullfile(pathToMLAPP, 'RX.png');
            app.PrescriptionsButton.Text = '';
            app.AccountButton = uibutton(app.UIFigure, 'push');
            app.AccountButton.Icon = fullfile(pathToMLAPP, 'PeopleCirclePlusIcon.png');
            app.AccountButton.Text = '';
            app.VaccinationsLabel = uilabel(app.UIFigure);
            app.VaccinationsLabel.Text = 'Vaccinations';
            app.PrescriptionsLabel = uilabel(app.UIFigure);
            app.PrescriptionsLabel.Text = 'Prescriptions';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.PrescriptionsTab = uitab(app.TabGroup);
            app.PrescriptionsTab.Title = 'Prescriptions';
            app.TabGroup2 = uitabgroup(app.PrescriptionsTab);
            app.AddTab = uitab(app.TabGroup2);
            app.AddTab.Title = 'Add';
            app.Image = uiimage(app.AddTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RX.png');
            app.ApplyButton = uibutton(app.AddTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.EditButton = uibutton(app.AddTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.uploadButton = uibutton(app.AddTab, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Icon = 'file_open.png';
            app.uploadButton.Text = 'upload';
            app.DrugNameEditFieldLabel = uilabel(app.AddTab);
            app.DrugNameEditFieldLabel.Text = 'Drug Name';
            app.DrugNameEditField = uieditfield(app.AddTab, 'text');
            app.DrugNameEditField.Value = '34';
            app.DatePrescrbedEditFieldLabel = uilabel(app.AddTab);
            app.DatePrescrbedEditFieldLabel.Text = 'Date Prescrbed';
            app.DatePrescrbedEditField = uieditfield(app.AddTab, 'text');
            app.DatePrescrbedEditField.Value = '324';
            app.DosageEditFieldLabel = uilabel(app.AddTab);
            app.DosageEditFieldLabel.Text = 'Dosage';
            app.DosageEditField = uieditfield(app.AddTab, 'text');
            app.DosageEditField.Value = '243';
            app.ExpirationEditFieldLabel = uilabel(app.AddTab);
            app.ExpirationEditFieldLabel.Text = 'Expiration';
            app.ExpirationEditField = uieditfield(app.AddTab, 'text');
            app.ExpirationEditField.Value = '234';
            app.NotesTextAreaLabel = uilabel(app.AddTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.NotesTextArea = uitextarea(app.AddTab);
            app.NotesTextArea.Value = {'324'};
            app.HistoryTab = uitab(app.TabGroup2);
            app.HistoryTab.Title = 'History';
            app.Image_2 = uiimage(app.HistoryTab);
            app.UITable = uitable(app.HistoryTab);
            app.UITable.ColumnName = {'ImageFile'; 'Drug Name'; 'Dosage'; 'Date Presicribed'; 'Expriration'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true false];
            app.UITable.ColumnEditable = [false true true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.VaccinationsTab = uitab(app.TabGroup);
            app.VaccinationsTab.Title = 'Vaccinations';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemoryMeds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end